classdef SquidLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SquidLabUIFigure             matlab.ui.Figure
        MCoakDJarvisCLiu2019Label    matlab.ui.control.Label
        SquidLabv296Label            matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        StatusPanel                  matlab.ui.container.Panel
        TabGroup2                    matlab.ui.container.TabGroup
        DataTab                      matlab.ui.container.Tab
        CopyCurvestoCustomPlotPanel  matlab.ui.container.Panel
        GreenButton                  matlab.ui.control.Button
        RedButton                    matlab.ui.control.Button
        BlueButton                   matlab.ui.control.Button
        RemoveScan                   matlab.ui.control.Button
        CopyfigureButton             matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        NumberOfScansLabel           matlab.ui.control.Label
        ScanToEditField              matlab.ui.control.NumericEditField
        toLabel                      matlab.ui.control.Label
        ScanFromSpinner              matlab.ui.control.Spinner
        UseeveryNthscanSpinner       matlab.ui.control.Spinner
        PlotScansButtonGroup         matlab.ui.container.ButtonGroup
        ScansButton                  matlab.ui.control.RadioButton
        EveryNthscanButton           matlab.ui.control.RadioButton
        AllscansButton               matlab.ui.control.RadioButton
        UIAxes                       matlab.ui.control.UIAxes
        ResultsTab                   matlab.ui.container.Tab
        CopyfigureButton_2           matlab.ui.control.Button
        ResultsAxes                  matlab.ui.control.UIAxes
        CustomPlotsTab               matlab.ui.container.Tab
        ClearButton                  matlab.ui.control.Button
        CopyfigureButton_3           matlab.ui.control.Button
        CustomPlotAxes               matlab.ui.control.UIAxes
        ScanSetsPanel                matlab.ui.container.Panel
        ViewHistoryButton            matlab.ui.control.Button
        DeleteScanSetButton          matlab.ui.control.Button
        RenameScanSetButton          matlab.ui.control.Button
        ScanSetListBox               matlab.ui.control.ListBox
        ConfigTabGroup               matlab.ui.container.TabGroup
        ImportTab                    matlab.ui.container.Tab
        AdvancedPanel                matlab.ui.container.Panel
        MirrorScansCheckBox          matlab.ui.control.CheckBox
        SortScansCheckBox            matlab.ui.control.CheckBox
        GetscalefactorSQUIDRangefromdatfileCheckBox  matlab.ui.control.CheckBox
        ScaleFactorEditField         matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel    matlab.ui.control.Label
        ZOffsetEditField             matlab.ui.control.NumericEditField
        zOffsetmmEditFieldLabel      matlab.ui.control.Label
        ScanSetNameDropDown          matlab.ui.control.DropDown
        ScanSetnameLabel             matlab.ui.control.Label
        ImportButton                 matlab.ui.control.Button
        ImportDropDown               matlab.ui.control.DropDown
        ImportDropDownLabel          matlab.ui.control.Label
        MeasurementEditField         matlab.ui.control.EditField
        ChooseMeasurementButton      matlab.ui.control.Button
        PostprocessTab               matlab.ui.container.Tab
        ProcessButton                matlab.ui.control.Button
        PostprocessPanel             matlab.ui.container.Panel
        SubtractbackgroundTab        matlab.ui.container.Tab
        ModeDropDown                 matlab.ui.control.DropDown
        ModeDropDownLabel            matlab.ui.control.Label
        SubtractBackgroundButton     matlab.ui.control.Button
        BackgroundDropDown           matlab.ui.control.DropDown
        BackgroundDropDownLabel      matlab.ui.control.Label
        DataDropDown                 matlab.ui.control.DropDown
        DataDropDownLabel            matlab.ui.control.Label
        FitTab                       matlab.ui.container.Tab
        FitPanel                     matlab.ui.container.Panel
        TypeDropDown                 matlab.ui.control.DropDown
        TypeDropDownLabel            matlab.ui.control.Label
        FitButton                    matlab.ui.control.Button
        LoadSaveTab                  matlab.ui.container.Tab
        SaveSessionButton            matlab.ui.control.Button
        LoadSessionButton            matlab.ui.control.Button
        ExporttoXYButton             matlab.ui.control.Button
        ExporttoworkspaceButton      matlab.ui.control.Button
    end

    %-------------------------------------Licensing and fair use notice---------------------------------------
    %---------------------------------------------------------------------------------------------------------
    %This code file forms part of the SquidLab software, University of Warwick and University of Cambridge
    %SquidLab remains the intellectual property of the Universities of Warwick and Cambridge, but is freely
    %distributed under an Academic Use license for non-commercial use. The license file is included with the
    %code files when downloaded, and must always be kept with them if distributed. Please also credit the
    %authors if the software contributes to any research results. Please read and make sure you agree with
    %the points in the (short) license document. In brief, you may use and also modify any of the code files
    %for non-commerical purposes, and should contact Warwick Ventures at the University of Warwick with any
    %questions as to potential commercial or profit-making use.
    %And remember, we can't guarantee that the software is bug or error free! Make sure to check any results.
    %---------------------------------------------------------------------------------------------------------
    
    properties (Access = public)
        %% Settings and user-editable properties--------------------------------
        
        %Edit to change the extensions shown in the open file dialogue. See
        %Matlab documention for 'uigetfile'
        FileExtensions = {  '*.dat;*.raw', 'MPMS data files (*.dat, *.raw)';...
            '*.raw', 'MPMS raw data files (*.raw)';...
            '*.eachpt', 'MPMS IQuantum raw data files (*.eachpt)';...
            '*.txt', 'Text Files (*.txt)';...
            '*.*', 'All Files (*.*)'...
            };
        
        %If this is not empty, all open file dialogues will default to this
        %directory - user can edit to their preference, eg DefaultPath = 'C:\Users\DrHorrible\Physics\Data\SQUID\';
        DefaultPath = '';
        
        %%-----------------------------------------------------------------------
        
        %% Internal properties - do not edit
        Meta; % Struct to add session-dependent info, like is this MvH or MvT, default coil parameters etc, for global reference.
    end
    
    
    properties (Access = private)   % Do not edit!
        Actions = squidlab.Actions() % Object for performing actions.
        ScanSets % Struct of scansets.
        HasBackgroundScanSet = false % True if a background ScanSet has been created.
        PostProcessInspector % Handle to the ObjectInspector for the PostProcessedScanSets
        FitInspector %
        ApplicationPath % Path the application was launched from, for things like dynamic class loading and relative paths
        ApplicationDir  %Directory application was launched from
        Logger % Handle to the object used to handle status logging
        CachedPlotScanSet % Currently plotted ScanSet, cached to avoid replotting if we can.
    end


    methods (Access = private)
        
        function seizeFocus(app)
            % Calling uigetfile or similar modal dialogs may send the app to the back.
            % Bring it back to the front, preserving maximised, minimised etc.
            windowState = app.SquidLabUIFigure.WindowState;
            app.SquidLabUIFigure.Visible = 'off';
            app.SquidLabUIFigure.Visible = 'on';
            app.SquidLabUIFigure.WindowState = windowState;
        end
        
        function handlePathSelect(app)
            % Called to select a file to import.
            if(isempty(app.DefaultPath))
                [file, path] = uigetfile(app.FileExtensions, 'Select file');
            else
                [file, path] = uigetfile(app.FileExtensions, 'Select file', app.DefaultPath);
            end
            
            if path
                app.MeasurementEditField.Value = fullfile(path, file);
            end
            app.seizeFocus();
        end
        
        function addScanSet(app, scanSet, targetName)
            % Adds a ScanSet, trying to give it the name targetName.
            % If this name is taken, a number is appended to the end of the name
            % so it's unique.
            
            scanSetName = app.nextNameFor(targetName);
            app.ScanSets.(scanSetName) = scanSet;
            scanSet.Name = scanSetName;
            app.update();
            app.selectScanSet(scanSetName);
        end
        
        function update(app)
            % Ensures the list of ScanSets, axes plot, and post-processing and fitting settings are
            % up to date with the current user selection.
            %
            
            % Currently there is an enormous performance hit when selecting a new ScanSet.
            % This is because a new PostProcessedScanSet and FitScanSet are always created, even
            % if they're not needed. This forces their ObjectInspector widgets to redraw, which
            % has a large performance cost.
            %
            % This should be fixed in a future version of the app.
            try
                if ~isempty(app.ScanSets)
                    scanSetNames = fields(app.ScanSets);
                    app.ScanSetListBox.Items = scanSetNames;
                    
                    app.DataDropDown.Items = scanSetNames;
                    app.BackgroundDropDown.Items = scanSetNames;
                    
                    [ss, name] = app.getCurrentScanSet();
                    app.ScanFromSpinner.Limits = [1 ss.NumScans];
                    app.ScanToEditField.Limits = [app.ScanFromSpinner.Value ss.NumScans+1];
                    noScans = ss.NumScans;
                    app.NumberOfScansLabel.Text = ['of ' num2str(noScans)];
                    
                    app.plotScanSet(ss, name);
                    
                    % Very performance-intensive.
                    app.createPostProcessScanSetFor(ss);
                    app.createFitScanSetFor(ss);
                end
            catch err
                ui.ErrorHandler.ErrorMessageBoxHandler('GUI update error', err);
            end
            
            
        end
        
        function createPostProcessScanSetFor(app, scanSet)
            ppScanSet = squidlab.scanset.PostProcessedScanSet(scanSet);
            ppScanSet.Name = scanSet.Name;
            
            % Explicitly delete the PostProcessInspector, otherwise there's a risk
            % it will leave its widgets behind in the panel.
            delete(app.PostProcessInspector);
            app.PostProcessInspector = ui.ObjectInspector(ppScanSet, app.PostprocessPanel);
            
            app.ProcessButton.Enable = 'on';
        end
        
        function createFitScanSetFor(app, scanSet)
            
            value = app.TypeDropDown.Value;
            if ~isempty(value)
                % Get the right type of FitScanSet.
                fitSSConstructor = str2func(string(value));
                fitScanSet = fitSSConstructor(scanSet);
                fitScanSet.Name = scanSet.Name;
                
                % Explicitly delete the FitInspector, otherwise there's a risk
                % it will leave its widgets behind in the panel.
                delete(app.FitInspector);
                fitScanSet.setMetaData(scanSet.Meta);
                app.FitInspector = ui.ObjectInspector(fitScanSet, app.FitPanel);
            end
        end
        
        function selectScanSet(app, scanSetName)
            % Selects a ScanSet by name.
            
            app.ScanSetListBox.Value = cellstr(scanSetName);
            app.update();
        end
        
        function plotScanSet(app, scanSet, scansetName, targetAxes)
            
            app.Logger.setBusy("Plotting");
            
            % By default use the app's axes, unless told otherwise.
            %
            % This lets us use the same plotting pipeline for plots in the app,
            % and plots to an external figure.
            if nargin == 3
                targetAxes = app.UIAxes;
            end
            
            % Only plot as many scans as the user wants.
            switch(app.PlotScansButtonGroup.SelectedObject.Text)
                case('All scans')
                    scanSet.useAll();
                case('Every Nth scan')
                    scanSet.useEvery(app.UseeveryNthscanSpinner.Value);
                case('Scans')
                    scanSet.UseScanRange(app.ScanFromSpinner.Value, app.ScanToEditField.Value);
                otherwise
                    error('Unsupported option');
            end
            
            % Do the plot.
            scanSet.Plotter.NewFigure = false;
            scanSet.Plotter.Axes = targetAxes;
            scanSet.Plotter.Meta = scanSet.Meta;
            scanSet.plot();
            targetAxes.Title.String = scansetName;
            targetAxes.Title.Interpreter = 'none';  %Prevent underscores in ScanSet names become subscripts.
            
            % Plot fit results in the Results tab instead of main plot
            if ismethod(scanSet, 'plotFitResults')
                scanSet.Plotter.Axes = app.ResultsAxes;
                scanSet.plotFitResults();
                scanSet.Plotter.Axes = targetAxes;
            end
            
            app.Logger.setFinished("Plotting");
        end
        
        function name = nextNameFor(app, requestedName)
            % Given a name request like "RawData", returns:
            % - "RawData" if there isn't currently a field called "RawData" in app.ScanSets.
            % - "RawData1", "RawData6", etc.
            
            %Max Matlab variable length is 63 characters..
            if(strlength(requestedName) > 62)
                warning('Variable name too long! Will be autotrimmed.');
                requestedName = extractBetween(requestedName, 1, 62);
            end
            
            if isempty(app.ScanSets)
                name = requestedName;
                return
            end
            
            existingNames = string(fields(app.ScanSets));
            
            if ~any(requestedName == existingNames)
                % Name doesn't already exist, so it's allowed.
                name = requestedName;
            else
                % Get the next available numbered name.
                matchingNames = existingNames(existingNames.startsWith(requestedName));
                
                if length(matchingNames) == 1
                    name = requestedName + "2";
                else
                    indices = double(matchingNames.extractAfter(requestedName));
                    name = requestedName + string(max(indices) + 1);
                end
            end
        end
        
        function [ss, ssName] = getCurrentScanSet(app)
            ssName = string(app.ScanSetListBox.Value);
            
            % Under some circumstances, a ScanSet may have been added without being selected.
            % If this happens, make sure we can at least return a ScanSet of some sort.
            if isempty(ssName)
                ssName = app.ScanSetListBox.Items{1};
            end
            
            ss = app.ScanSets.(ssName);
        end
        
        function removeScanFromCurrentScanSet(app, scanStartNo, scanEndNo) %Experimental - if clicking though, one finds that Scan 15 in a Scanset has anomalous points in it, one could elect to remove Scan number 15...
            ssName = string(app.ScanSetListBox.Value);
            
            % Under some circumstances, a ScanSet may have been added without being selected.
            % If this happens, make sure we can at least return a ScanSet of some sort.
            if isempty(ssName)
                ssName = app.ScanSetListBox.Items{1};
            end
            ss = app.ScanSets.(ssName);
            
            %Remove the scans
            scansToGo = scanStartNo : scanEndNo;
            ss.removeScans(scansToGo);
            
            %Save the modified Scanset back in
            app.ScanSets.(ssName) = ss;
            
            %Tell the user we did this
            disp(['Removed scans ', num2str(scanStartNo), ' to ' , num2str(scanEndNo)]);
            app.Logger.setFinished(['Removed scans ', num2str(scanStartNo), ' to ' , num2str(scanEndNo)]);
            
            %Update everything
            app.update();
        end
        
        
        function removeScanSet(app, ssName)
            app.ScanSets = rmfield(app.ScanSets, ssName);
            app.ScanSetListBox.Items = fieldnames(app.ScanSets);
        end
        
        function meta = QueryScanSetMetaData(~, scanSetName)
            %Default values - copied from MPMSXL.m
            meta.XVar = 'T';
            meta.CalibrationFactor = '1.096e-3';
            meta.CoilRadius = '9.7';
            meta.CoilSeparation = '15.19';
            meta.CryostatInfo = 'MPMS or MPMSXL, standard format, MvT'; 
            
                        
            width = 70;
            
            answer = inputdlg({'X variable type, T or H', 'Calibration Factor; 1.096e-3 for MPMS, -5.966e-7 for MPMS3', 'Coil Radius; 9.7 for MPMS, 8.5 for MPMS3', ' Coil Separation; 15.19 for MPMS, 8 for MPMS3', 'Cryostat Info'},...
                strcat("Upgrade ScanSet '", scanSetName, "'"),...
                [1 width; 1 width; 1, width; 1, width; 1, width;],...
                {meta.XVar, meta.CalibrationFactor, meta.CoilRadius, meta.CoilSeparation, meta.CryostatInfo});
            
            if(isempty(answer))
                return;
            end
            
            meta.XVar = answer{1};
            meta.CalibrationFactor = str2double(answer{2});
            meta.CoilRadius = str2double(answer{3});
            meta.CoilSeparation = str2double(answer{4});
            meta.CryostatInfo = answer{5};
        end
        
        function loadSession(app, filePath)
            try
                app.Logger.setBusy("Loading");
                
                scanSets = load(filePath);
                
                if(isempty(scanSets))
                    app.Logger.logError('Session is empty or invalid, cannot load');
                    return;
                end
                
                %Check for obsolete save versions which do not have
                %metadata saved in the ScanSet. Prompt for entry and
                %upgrade them if so.
                scanSetNames = fieldnames(scanSets.ScanSets);
                for i = 1: length(scanSetNames)
                    scanSetName = scanSetNames{i};
                   if(~isprop(scanSets.ScanSets.(scanSetName), 'Meta') || isempty(scanSets.ScanSets.(scanSetName).Meta))
                       scanSets.ScanSets.(scanSetName).Meta = app.QueryScanSetMetaData(scanSetName);
                   end
                end
                
                app.ScanSets = scanSets.ScanSets;
                app.Logger.setFinished("Loading");
                app.update;
                
            catch err
                ui.ErrorHandler.ErrorMessageBoxHandler('Session load error', err);
                app.Logger.logError('Session load error');
            end
        end
        
        function saveSession(app, filePath)
            try
                app.Logger.setBusy("Saving");
                
                ScanSets = app.ScanSets; %#ok<ADPROPLC>
                
                if(isempty(ScanSets)) %#ok<ADPROPLC>
                    app.Logger.logError('Session is empty, cannot save');
                    return;
                end
                
                save(filePath, 'ScanSets');
                
                app.Logger.setFinished("Saving");
                
            catch err
                ui.ErrorHandler.ErrorMessageBoxHandler('Session save error', err);
                app.Logger.logError('Session save error');
            end
        end
        
        function AddCurveToCustomPlot(app, colour)
            try
                %Retrieve axis reference to copy from
                ax = app.UIAxes;
                
                %and target axex
                tax = app.CustomPlotAxes;
                
                dataLines = fliplr(ax.XAxis.Parent.Children');
                for i = 1 : length(dataLines)
                    %Extract data from axes and plot in new figure, with
                    %specified colour
                    line = plot(tax, dataLines(i).XData, dataLines(i).YData, 'Marker', dataLines(i).Marker, 'LineStyle', dataLines(i).LineStyle, 'LineWidth', dataLines(i).LineWidth, 'Color', colour);
                    line.DisplayName = ax.Title.String;
                end
                
                %Add a legend - plots have names now
                lgd = legend(tax);
                lgd.Box = 'off';
                
            catch err
                ui.ErrorHandler.ErrorMessageBoxHandler('Add Custom Plot error', err);
                app.Logger.logError('Add Custom Plot error');
            end
        end
        
        function ClearCustomPlot(app)
            try
                % delete(app.CustomPlotAxes);
                % app.CustomPlotAxes = axes();
                cla(app.CustomPlotAxes);
            catch err
                ui.ErrorHandler.ErrorMessageBoxHandler('Clear Custom Plot error', err);
                app.Logger.logError('Clear Custom Plot error');
            end
        end
        
        function classNames = LoadClassNamesInNamespace(app, namespaceString)
            %Get a list (array of strings) of the names of all classes
            %defined in a namespace.
            arguments
                app;
                namespaceString string {mustBeTextScalar};
            end

            classData = namespaceClasses(namespaceString);

            if isempty(classData)
                warning("Namespace " + namespaceString + " is empty, no classes found. Not on the path?");
                classNames = [];
                return;
            end

            classNames = [];

            for i = 1 : length(classData)
                str = string(classData(i).Name);
                mc = meta.class.fromName(str);
                isConcrete = ~mc.Abstract;

                %Add only non-abstract classes
                if isConcrete
                    if isempty(classNames)
                        classNames = str;
                    else
                        classNames = [classNames, str];
                    end
                end
              %  classNames(i) = erase(str, namespaceString + ".");     %We
              %  want the namespace still attached in this case
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, file)
             %Make sure user has the required matlab version first of all (9.5 is R2018b, 9.7 is R2019b - which has the proper GUI controls, so force that. 2020b to be safest actually)
            try               
                ui.ErrorHandler.VerifyMatlabVersion(9,9);
            catch err
                warning('Matlab version 2020b or newer must be installed to run SquidLab');
                ui.ErrorHandler.ErrorMessageBoxHandler('Matlab version error', err);
            end
            
            %Make sure the user has the required toolboxes installed
            try
                ui.ErrorHandler.VerifyToolboxInstalled('Curve Fitting Toolbox');
            catch err
                warning('Curve Fitting Toolbox must be installed to run SquidLab');
                ui.ErrorHandler.ErrorMessageBoxHandler('Toolbox verification error', err);
            end
            try
                ui.ErrorHandler.VerifyToolboxInstalled('Statistics and Machine Learning Toolbox');
            catch err
                warning('Statistics and Machine Learning Toolbox must be installed to run SquidLab');
                ui.ErrorHandler.ErrorMessageBoxHandler('Toolbox verification error', err);
            end
            
            try
                %Set application paths for loading of child classes later
                app.ApplicationPath = mfilename('fullpath');
                [app.ApplicationDir, ~, ~] = fileparts(app.ApplicationPath);
                
                % Load import functions.
                importers = app.LoadClassNamesInNamespace("squidlab.import");
                              
                % Set up logger. Give a handle to the Actions so updates are published to the app.
                app.StatusPanel.Units = "pixels";
                loggerPos = app.StatusPanel.Position;
                app.Logger = squidlab.utils.UILogger(app.SquidLabUIFigure, app.StatusPanel, [0 0 loggerPos(3:4)]);
                app.Actions.Logger = app.Logger;
                
                if isempty(importers)
                    error("No Importers found in squidlab.import namespace");
                end

                app.ImportDropDown.Items = importers;
            catch err
                warning(['Startup error ' err.message]);
                ui.ErrorHandler.ErrorMessageBoxHandler('Startup error', err);               
            end

            %Disable or hide UI elements in deployed code that make no sense
            %there
            if isdeployed
                app.ExporttoworkspaceButton.Visible = "off";
            end
        end

        % Button pushed function: ChooseMeasurementButton
        function ChooseMeasurementButtonPushed(app, event)
            app.handlePathSelect();
            app.seizeFocus();
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            %Check that a filepath has been entered
            if(isempty(app.MeasurementEditField.Value))
                app.Logger.showWarning("No file selected!", "No file selected");
                return;
            end
            
            %..and check that that file actually exists!
            if(~isfile(app.MeasurementEditField.Value))
                app.Logger.showWarning("File not found!", "File not found");
                return;
            end            
            
            %..and that the scanset name entered is valid!
            if(~isvarname(app.ScanSetNameDropDown.Value))
                app.Logger.showWarning(app.ScanSetNameDropDown.Value + " is not a valid Matlab variable name. The most common causes for this are that variable names must start with a letter, and cannot contain - dashes. Please re-enter ScanSet name and try again.", "Invalid ScanSet name");
                return;
            end
            
            try
                app.Logger.setBusy("Parsing data");
                
                %Overwrite the scalefactor with 1 if using the "rescale from file"
                %option. Also prevent the warning box informing user of
                %quidrange issue popping up.
                if app.GetscalefactorSQUIDRangefromdatfileCheckBox.Value
                    app.ScaleFactorEditField.Value = 1;
                    app.Logger.SuppressSquidRangeWarning = true;
                end
                
                %Import the data
                [results, info] = app.Actions.importData(...
                    app.MeasurementEditField.Value, app.ImportDropDown.Value, app.ScaleFactorEditField.Value);
                
                %If using "rescale from file", apply the correction to
                %imported results.
                if app.GetscalefactorSQUIDRangefromdatfileCheckBox.Value
                    results = app.Actions.rescaleFromFile(results);
                    app.seizeFocus();
                end
                
                app.Logger.setFinished("Parsing data");
                
                %Subtract any z offset. Z values should be in mm at this
                %point.
                zOffset = app.ZOffsetEditField.Value;
                if(~(zOffset == 0))
                    for i = 1 : size(results.ScanData, 1)
                        for j = 1 : size(results.ScanData, 3)
                            results.ScanData(i,1,j) = results.ScanData(i,1,j) + zOffset;
                        end
                    end
                end
                
                %Mirror the scans through zero if this option checked. Sane
                %users will of course have no business with negative
                %temperatures and hence this is assuming that the imported
                %sweep is a field sweep - might have a hysteresis loop for
                %the sample and only +ve field values for the background
                mirrorScans = app.MirrorScansCheckBox.Value;
                if(mirrorScans)
                   results = app.Actions.mirrorData(results);                    
                end
                
                
                %Sort the data by field/temperature if this option checked
                sortData = app.SortScansCheckBox.Value;
                if(sortData)
                    %Sort by 'temperature' - note that 'temperature' just
                    %means the independent variable, may well actually be
                    %field. idx is an index array which we can use to sort
                    %the 'Fields' and the scans
                    [sortedTemperatures, idx] = sort(results.Temperatures);
                    
                    %Sort the fields and the scans
                    sortedFields = results.Fields(idx);
                    sortedScanData = results.ScanData(:,:, idx);
                    
                    %Set these as the new data
                    results.Fields = sortedFields;
                    results.Temperatures = sortedTemperatures;
                    results.ScanData = sortedScanData;
                end
                
                %Get import metadata
                ss_Meta = info.Meta;
                
                app.Logger.setBusy("Creating ScanSet");
                
                %Create a scanset to hold the data
                scanSet = squidlab.scanset.RawScanSet(results.Temperatures, results.Fields, results.ScanData);
                scanSet.Meta = ss_Meta;
                scanSet.Meta.History = "<Import>" + newline + "File: " + app.MeasurementEditField.Value + newline + "ScaleFactor: " + string(app.ScaleFactorEditField.Value);
                
                %Add the scanset to the list of registered a=ones
                app.addScanSet(scanSet, app.ScanSetNameDropDown.Value);
                
                %Get fit metadata loaded in to the Fit panel
                app.FitInspector.Object.setMetaData(ss_Meta);
                
                app.Logger.setFinished("Creating ScanSet");
            catch err
                ui.ErrorHandler.ErrorMessageBoxHandler('Import failed - the import pipeline threw an error. Is this the correct Importer for this file type? Import failed.', err);
                app.Logger.logError('Data import error');
            end
            
            %Reset this flag
            app.Logger.SuppressSquidRangeWarning = true;
        end

        % Callback function
        function ApplytoDropDownValueChanged(app, event)
            value = app.ApplytoDropDown.Value;
            newName = "PPrcd" + value;
            app.ScanSets.(newName) = squidlab.scanset.PostProcessedScanSet(app.ScanSets.(value));
            app.PostProcessInspector = ui.ObjectInspector(app.ScanSets.(newName), app.PostprocessPanel);
            app.ProcessButton.Enable = 'on';
            app.update();
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            try
                app.PostProcessInspector.setProperties();
                app.PostProcessInspector.Object.postProcess();
                
                [~, ssName] = app.getCurrentScanSet();
                app.addScanSet(app.PostProcessInspector.Object, "PPrcd" + ssName);
            catch err
                ui.ErrorHandler.ErrorMessageBoxHandler('Data process error', err);
                app.Logger.logError('Data process error');
            end
        end

        % Value changed function: ScanSetListBox
        function ScanSetListBoxValueChanged(app, event)
            value = app.ScanSetListBox.Value;
            app.selectScanSet(value);
        end

        % Selection change function: ConfigTabGroup
        function ConfigTabGroupSelectionChanged(app, event)
            
        end

        % Button pushed function: SubtractBackgroundButton
        function SubtractBackgroundButtonPushed(app, event)
            try
                dataSS = app.ScanSets.(string(app.DataDropDown.Value));
                bkgSS = app.ScanSets.(string(app.BackgroundDropDown.Value));
                mode = app.ModeDropDown.Value;
                
                bkgSubtractedSS = squidlab.scanset.BackgroundSubtractedScanSet(dataSS, bkgSS, mode);
                newName = "BackSub" + string(app.DataDropDown.Value);
                app.addScanSet(bkgSubtractedSS, newName);
                
            catch err
                ui.ErrorHandler.ErrorMessageBoxHandler('Data subtract error', err);
                app.Logger.logError('Data subtract error');
            end
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            try
                app.FitInspector.setProperties();
                app.FitInspector.Object.fit();
                
                %Enable the export to xy button
                app.ExporttoXYButton.Enable = true;
                
                [~, ssName] = app.getCurrentScanSet();
                app.addScanSet(app.FitInspector.Object, "Fitted" + ssName);
            catch err
                ui.ErrorHandler.ErrorMessageBoxHandler('Data fit error', err);
                app.Logger.logError('Data fit error');
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            app.createFitScanSetFor(app.getCurrentScanSet());
        end

        % Callback function
        function UseAllSwitchValueChanged(app, event)
            app.update();
        end

        % Value changed function: UseeveryNthscanSpinner
        function UseeveryNthscanSpinnerValueChanged(app, event)
            app.update();
        end

        % Button pushed function: CopyfigureButton
        function CopyfigureButtonPushed(app, event)
            try
                [ss, name] = app.getCurrentScanSet();
                f = figure('Color', 'w');
                a = axes(f);
                app.plotScanSet(ss, name, a);
            catch err
                ui.ErrorHandler.ErrorMessageBoxHandler('Copy figure error', err);
                app.Logger.logError('Copy figure error');
            end
        end

        % Button pushed function: ExporttoworkspaceButton
        function ExporttoworkspaceButtonPushed(app, event)
            try
                scansetNames = fields(app.ScanSets);
                for i=1:length(scansetNames)
                    assignin('base', scansetNames{i}, app.ScanSets.(scansetNames{i}));
                end
                
                %Try grabbing x and y of results plot and dump those to
                %workspace too, just makes things easier
                try
                    Results.x = app.ResultsAxes.Children(1).XData;
                    Results.y = app.ResultsAxes.Children(1).YData;
                    assignin('base', 'Results', Results);
                catch
                    %no need to do anything, this is optional anyway
                end
            catch err
                ui.ErrorHandler.ErrorMessageBoxHandler('Data export error', err);
                app.Logger.logError('Data export error');
            end
        end

        % Button pushed function: ExporttoXYButton
        function ExporttoXYButtonPushed(app, event)
            xlab = app.ResultsAxes.XLabel.String{1};
            ylab = app.ResultsAxes.YLabel.String{1};
            x = app.ResultsAxes.Children(1).XData;
            y = app.ResultsAxes.Children(1).YData;
            
            %Write data to txt
            [file,path] = uiputfile('*.txt','Save fitted signal data');
            if(path == 0)
                return; % Handle cancel operation
            end
            
            FilePath = [path file];
            data = [x', y'];
            headers = sprintf('%s\t%s', xlab, ylab);
            fid = fopen(FilePath,'w');
            fprintf(fid, '%s\r\n', headers);
            fclose(fid);
            dlmwrite(FilePath, data, '-append', 'delimiter', '\t', 'precision', '%12.12e', 'newline','pc');
        end

        % Selection changed function: PlotScansButtonGroup
        function PlotScansButtonGroupSelectionChanged(app, event)
            app.update();
            
            % Enable the Remove Scans button only when selecting Scans mode
            switch(app.PlotScansButtonGroup.SelectedObject.Text)
                case('All scans')
                    app.RemoveScan.Enable = false;
                case('Every Nth scan')
                    app.RemoveScan.Enable = false;
                case('Scans')
                    app.RemoveScan.Enable = true;
                otherwise
                    error('Unsupported option');
            end
        end

        % Button pushed function: CopyfigureButton_2
        function CopyfigureButton_2Pushed(app, event)
            [scanSet, ~] = app.getCurrentScanSet();
            f = figure('Color', 'w');
            a = axes(f);
            scanSet.Plotter.Axes = a;
            scanSet.plotFitResults();
            scanSet.Plotter.Axes = app.UIAxes;
        end

        % Value changed function: ScanFromSpinner
        function ScanFromSpinnerValueChanged(app, event)
            %This logic ensures the To scan number box limits are kept
            %between the From number and the maximum, and when we increment
            %or decrement the From box, the To box moves with it.
            app.ScanToEditField.Limits(1) = 1;
            %Note that to make sure limits are not made equal when we hit
            %NumberOfScans on both boxes, upper llimit of the To input box
            %is exclusive and set to 1 more than the NumberOfScans
            app.ScanToEditField.Value = max(app.ScanFromSpinner.Value, min(app.ScanToEditField.Limits(2)-1, app.ScanToEditField.Value + event.Value - event.PreviousValue));  %Make clicking the increment/decrement buttons on the From box also change the To
            app.ScanToEditField.Limits(1) = min(app.ScanFromSpinner.Limits(2)-1, app.ScanFromSpinner.Value);
            
            %Now replot everything
            app.update();
        end

        % Value changed function: ScanToEditField
        function ScanToEditFieldValueChanged(app, event)
            app.update();
        end

        % Button pushed function: RenameScanSetButton
        function RenameScanSetButtonPushed(app, event)
           %If there aren't any scansets.. jsut return
           if(isempty(app.ScanSetListBox.Items))
               return;
           end
           
            newName = string(inputdlg("Enter new name:"));
            oldName = app.ScanSetListBox.Value;
            
             %Verify that the scanset name entered is valid!
            if(~isvarname(newName))
                app.Logger.showWarning(string(newName) + " is not a valid Matlab variable name. The most common causes for this are that variable names must start with a letter, and cannot contain - dashes. No changes have been made. Please try again with a valid name.", "Invalid ScanSet name");
                return;
            end
            
            ss = app.getCurrentScanSet();
            
            app.removeScanSet(oldName);
            app.addScanSet(ss, newName);
        end

        % Button pushed function: DeleteScanSetButton
        function DeleteScanSetButtonPushed(app, event)
         %If there aren't any scansets.. jsut return
           if(isempty(app.ScanSetListBox.Items))
               return;
           end
           ssName = string(app.ScanSetListBox.Value);
           app.removeScanSet(ssName);
        end

        % Button pushed function: RemoveScan
        function RemoveScanButtonPushed(app, event)
            scanStartNo = app.ScanFromSpinner.Value;
            scanEndNo = app.ScanToEditField.Value;
            
            app.removeScanFromCurrentScanSet(scanStartNo, scanEndNo);
        end

        % Button pushed function: LoadSessionButton
        function LoadSessionButtonPushed(app, event)
            sessionFileExtensions = {'*.mat', 'SquidLab Matlab files'};
            
            % Called to select a file to import.
            if(isempty(app.DefaultPath))
                [file, path] = uigetfile(sessionFileExtensions, 'Select Saved Session to load');
            else
                [file, path] = uigetfile(sessionFileExtensions, 'Select Saved Session to load', app.DefaultPath);
            end
            
            %Bring the SquidLab window back to the front
            app.seizeFocus();
            
            if path     %If a valid file was selected
                filePath = fullfile(path, file);
                app.loadSession(filePath);
            end
        end

        % Button pushed function: SaveSessionButton
        function SaveSessionButtonPushed(app, event)
            sessionFileExtensions = {'*.mat', 'SquidLab Matlab files'};
            
            % Called to select a file to import.
            if(isempty(app.DefaultPath))
                [file, path] = uiputfile(sessionFileExtensions, 'Select Session save path');
            else
                [file, path] = uiputfile(sessionFileExtensions, 'Select Session save path', app.DefaultPath);
            end
            
            %Bring the SquidLab window back to the front
            app.seizeFocus();
            
            if path     %If a valid file was selected
                filePath = fullfile(path, file);
                app.saveSession(filePath);
            end
        end

        % Button pushed function: CopyfigureButton_3
        function CopyfigureButton_3Pushed(app, event)
            %Create a new figure
            h = figure('Position', [100 100 1000 700]);
            newAx = axes(h, 'Box', 'on');
            
            %Retrieve axis reference to copy from
            ax = app.CustomPlotAxes;
            
            %Hide the figure so it doesnt jump around horribly as it
            %updates
            h.Visible = 'off';
            
            hold on;
            dataLines = fliplr(ax.XAxis.Parent.Children');
            for i = 1 : length(dataLines)
                %Extract data from axes and plot in new figure
                line = plot(dataLines(i).XData, dataLines(i).YData, 'Marker', dataLines(i).Marker, 'LineStyle', dataLines(i).LineStyle, 'LineWidth', dataLines(i).LineWidth, 'Color', dataLines(i).Color);
                line.DisplayName = dataLines(i).DisplayName;
            end
            
            %Add a legend - plots have names now
            lgd = legend;
            lgd.Box = 'off';
            
            %Copy axes labels
            newAx.XLabel.String = ax.XLabel.String;
            newAx.YLabel.String = ax.YLabel.String;
            newAx.XLabel.FontSize = ax.XLabel.FontSize;
            newAx.YLabel.FontSize = ax.YLabel.FontSize;
            
            newAx.FontSize = 18;
            
            %Set to visible again now its done
            h.Visible = 'on';
            hold off;
        end

        % Button pushed function: BlueButton
        function BlueButtonPushed(app, event)
            colour = [0 0 1];
            app.AddCurveToCustomPlot(colour);
        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            colour = [1 0 0];
            app.AddCurveToCustomPlot(colour);
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
            colour = [0.47,0.67,0.19];
            app.AddCurveToCustomPlot(colour);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ClearCustomPlot();
        end

        % Button pushed function: ViewHistoryButton
        function ViewHistoryButtonPushed(app, event)
            %If there aren't any scansets.. jsut return
            if(isempty(app.ScanSetListBox.Items))
                return;
            end

            ss = app.getCurrentScanSet();

            %Create a uifigure window to show the history in
            f = uifigure('Name', ss.Name, Icon=app.SquidLabUIFigure.Icon);
            gl = uigridlayout(f, [1 1]);
            gl.Padding = [0 0 0 0];
            gl.RowSpacing = 0;
            gl.ColumnSpacing = 0;
            ta = uitextarea(gl, Editable="off");
            ss.DisplayHistory(ta)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SquidLabUIFigure = uifigure('Visible', 'off');
            app.SquidLabUIFigure.Name = 'SquidLab';
            app.SquidLabUIFigure.Icon = 'SquidLabLogo.jpg';
            app.ConfigTabGroup = uitabgroup(app.SquidLabUIFigure);
            app.ConfigTabGroup.SelectionChangedFcn = createCallbackFcn(app, @ConfigTabGroupSelectionChanged, true);
            app.ImportTab = uitab(app.ConfigTabGroup);
            app.ImportTab.Title = 'Import';
            app.ChooseMeasurementButton = uibutton(app.ImportTab, 'push');
            app.ChooseMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseMeasurementButtonPushed, true);
            app.ChooseMeasurementButton.Text = 'Choose datafile';
            app.MeasurementEditField = uieditfield(app.ImportTab, 'text');
            app.ImportDropDownLabel = uilabel(app.ImportTab);
            app.ImportDropDownLabel.Text = 'Importer:';
            app.ImportDropDown = uidropdown(app.ImportTab);
            app.ImportDropDown.Items = {'squidlab.import.CryogenicS700X', 'squidlab.import.MPMS'};
            app.ImportDropDown.Editable = 'on';
            app.ImportDropDown.Value = 'squidlab.import.CryogenicS700X';
            app.ImportButton = uibutton(app.ImportTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Tooltip = {'Import the data into a ScanSet once all parameters are entered above. '};
            app.ImportButton.Text = 'Import';
            app.ScanSetnameLabel = uilabel(app.ImportTab);
            app.ScanSetnameLabel.Text = 'ScanSet name:';
            app.ScanSetNameDropDown = uidropdown(app.ImportTab);
            app.ScanSetNameDropDown.Items = {'RawData', 'BackgroundData'};
            app.ScanSetNameDropDown.Editable = 'on';
            app.ScanSetNameDropDown.Tooltip = {'Select the name to give the imported ScanSet. Duplicates will be renamed to eg RawData2. Default values are given in the drop-down for convenience, but you can alternatively enter any valid Matlab variable name into the Edit Box.'};
            app.ScanSetNameDropDown.Value = 'RawData';
            app.AdvancedPanel = uipanel(app.ImportTab);
            app.AdvancedPanel.Title = 'Advanced';
            app.zOffsetmmEditFieldLabel = uilabel(app.AdvancedPanel);
            app.zOffsetmmEditFieldLabel.Text = 'z Offset (mm)';
            app.ZOffsetEditField = uieditfield(app.AdvancedPanel, 'numeric');
            app.ZOffsetEditField.Tooltip = {'For the power user only! Offset the z values of the imported scans by this amount. Use if background and data scans are not   centred on the same point.'};
            app.ScaleFactorEditFieldLabel = uilabel(app.AdvancedPanel);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.AdvancedPanel, 'numeric');
            app.ScaleFactorEditField.Tooltip = {'Scale the Voltage data by this factor. Shouldn''t be required! But handy for testing and for if you get the squidrange = 1000 bug.'};
            app.ScaleFactorEditField.Value = 1;
            app.GetscalefactorSQUIDRangefromdatfileCheckBox = uicheckbox(app.AdvancedPanel);
            app.GetscalefactorSQUIDRangefromdatfileCheckBox.Tooltip = {'Shouldn''t be required! Tick this only if you get the warning messages about SquidRange=1000 when importing certain bugged MPMS3 datafiles. This will prompt you to open the corresponding .dat file, where the correct ranges can be extracted and applied to the imported data.'};
            app.GetscalefactorSQUIDRangefromdatfileCheckBox.Text = 'Get scale factor / SQUID Range from .dat file';
            app.SortScansCheckBox = uicheckbox(app.AdvancedPanel);
            app.SortScansCheckBox.Tooltip = {'Tick to sort the scanset scans in ascending temperature/field order. If unchecked, will preserve order in datafile.'};
            app.SortScansCheckBox.Text = 'Sort scans by temperature/field';
            app.SortScansCheckBox.Value = true;
            app.MirrorScansCheckBox = uicheckbox(app.AdvancedPanel);
            app.MirrorScansCheckBox.Tooltip = {'Tick to generate a negative field equivalent for all the positive field scans in this dataset. Data will simply be mirrored through B=0. Use if e.g. you have a sample with a measured hysteresis loop but only positive B values in your background (and you assume no hysteresis in background).'};
            app.MirrorScansCheckBox.Text = 'Mirror positive field scans to create negative scans';
            app.PostprocessTab = uitab(app.ConfigTabGroup);
            app.PostprocessTab.Title = 'Post-process';
            app.PostprocessPanel = uipanel(app.PostprocessTab);
            app.ProcessButton = uibutton(app.PostprocessTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Tooltip = {'Perform the Post Processing tasks specified below on the currently selected ScanSet.'};
            app.ProcessButton.Text = 'Process';
            app.SubtractbackgroundTab = uitab(app.ConfigTabGroup);
            app.SubtractbackgroundTab.Title = 'Subtract background';
            app.DataDropDownLabel = uilabel(app.SubtractbackgroundTab);
            app.DataDropDownLabel.Text = 'Data:';
            app.DataDropDown = uidropdown(app.SubtractbackgroundTab);
            app.DataDropDown.Items = {};
            app.DataDropDown.Value = {};
            app.BackgroundDropDownLabel = uilabel(app.SubtractbackgroundTab);
            app.BackgroundDropDownLabel.Text = 'Background:';
            app.BackgroundDropDown = uidropdown(app.SubtractbackgroundTab);
            app.BackgroundDropDown.Items = {};
            app.BackgroundDropDown.Value = {};
            app.SubtractBackgroundButton = uibutton(app.SubtractbackgroundTab, 'push');
            app.SubtractBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractBackgroundButtonPushed, true);
            app.SubtractBackgroundButton.Tooltip = {'Subtract the Data and Background ScanSets chosen below. Not the currently selected data mind!'};
            app.SubtractBackgroundButton.Text = 'Subtract background';
            app.ModeDropDownLabel = uilabel(app.SubtractbackgroundTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.SubtractbackgroundTab);
            app.ModeDropDown.Items = {'interp', 'nearest'};
            app.ModeDropDown.Value = 'interp';
            app.FitTab = uitab(app.ConfigTabGroup);
            app.FitTab.Title = 'Fit';
            app.FitButton = uibutton(app.FitTab, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Tooltip = {'Perform the fit as specified below on the currently selected ScanSet.'};
            app.FitButton.Text = 'Fit';
            app.TypeDropDownLabel = uilabel(app.FitTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.FitTab);
            app.TypeDropDown.Items = {'squidlab.scanset.LevenbergMarquadtFitScanSet', 'squidlab.scanset.SVDFitScanSet'};
            app.TypeDropDown.Editable = 'on';
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'squidlab.scanset.LevenbergMarquadtFitScanSet';
            app.FitPanel = uipanel(app.FitTab);
            app.LoadSaveTab = uitab(app.ConfigTabGroup);
            app.LoadSaveTab.Title = 'Load / Save';
            app.ExporttoworkspaceButton = uibutton(app.LoadSaveTab, 'push');
            app.ExporttoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoworkspaceButtonPushed, true);
            app.ExporttoworkspaceButton.Tooltip = {'Save all the ScanSet objects to the current Matlab Workspace for further analysis. If there is a fit result shown int he Results tab, this will be exported as Results.x and Results.y too.'};
            app.ExporttoworkspaceButton.Text = 'Export to workspace';
            app.ExporttoXYButton = uibutton(app.LoadSaveTab, 'push');
            app.ExporttoXYButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoXYButtonPushed, true);
            app.ExporttoXYButton.Enable = 'off';
            app.ExporttoXYButton.Tooltip = {'Save the Results data to a txt file.'};
            app.ExporttoXYButton.Text = 'Export to XY';
            app.LoadSessionButton = uibutton(app.LoadSaveTab, 'push');
            app.LoadSessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSessionButtonPushed, true);
            app.LoadSessionButton.Tooltip = {'Load a saved Session from file. Current work will be lost!'};
            app.LoadSessionButton.Text = 'Load Session';
            app.SaveSessionButton = uibutton(app.LoadSaveTab, 'push');
            app.SaveSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSessionButtonPushed, true);
            app.SaveSessionButton.Tooltip = {'Save the current Session to file, to resume later.'};
            app.SaveSessionButton.Text = 'Save Session';
            app.ScanSetsPanel = uipanel(app.SquidLabUIFigure);
            app.ScanSetsPanel.Title = 'ScanSets';
            app.ScanSetListBox = uilistbox(app.ScanSetsPanel);
            app.ScanSetListBox.Items = {};
            app.ScanSetListBox.Multiselect = 'on';
            app.ScanSetListBox.ValueChangedFcn = createCallbackFcn(app, @ScanSetListBoxValueChanged, true);
            app.ScanSetListBox.Value = {};
            app.RenameScanSetButton = uibutton(app.ScanSetsPanel, 'push');
            app.RenameScanSetButton.ButtonPushedFcn = createCallbackFcn(app, @RenameScanSetButtonPushed, true);
            app.RenameScanSetButton.Tooltip = {'Rename the selected ScanSet.'};
            app.RenameScanSetButton.Text = 'Rename ScanSet';
            app.DeleteScanSetButton = uibutton(app.ScanSetsPanel, 'push');
            app.DeleteScanSetButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteScanSetButtonPushed, true);
            app.DeleteScanSetButton.Tooltip = {'Remove the selected ScanSet from this list.'};
            app.DeleteScanSetButton.Text = 'Delete ScanSet';
            app.ViewHistoryButton = uibutton(app.ScanSetsPanel, 'push');
            app.ViewHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ViewHistoryButtonPushed, true);
            app.ViewHistoryButton.Tooltip = {'Open a text window which lists the history of this scanset - a list of all the operations carried out on it, so it''s possible to reproduce e.g. the SmoothingSpan used to get this result.'};
            app.ViewHistoryButton.Text = 'View History';
            app.TabGroup2 = uitabgroup(app.SquidLabUIFigure);
            app.DataTab = uitab(app.TabGroup2);
            app.DataTab.Title = 'Data';
            app.UIAxes = uiaxes(app.DataTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTickLabelRotationMode = 'manual';
            app.UIAxes.YTickLabelRotationMode = 'manual';
            app.UIAxes.ZTickLabelRotationMode = 'manual';
            app.Panel = uipanel(app.DataTab);
            app.PlotScansButtonGroup = uibuttongroup(app.Panel);
            app.PlotScansButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotScansButtonGroupSelectionChanged, true);
            app.AllscansButton = uiradiobutton(app.PlotScansButtonGroup);
            app.AllscansButton.Text = 'All scans';
            app.AllscansButton.Value = true;
            app.EveryNthscanButton = uiradiobutton(app.PlotScansButtonGroup);
            app.EveryNthscanButton.Text = 'Every Nth scan';
            app.ScansButton = uiradiobutton(app.PlotScansButtonGroup);
            app.ScansButton.Text = 'Scans';
            app.UseeveryNthscanSpinner = uispinner(app.Panel);
            app.UseeveryNthscanSpinner.Limits = [1 Inf];
            app.UseeveryNthscanSpinner.ValueDisplayFormat = '%.0f';
            app.UseeveryNthscanSpinner.ValueChangedFcn = createCallbackFcn(app, @UseeveryNthscanSpinnerValueChanged, true);
            app.UseeveryNthscanSpinner.Value = 8;
            app.ScanFromSpinner = uispinner(app.Panel);
            app.ScanFromSpinner.Limits = [1 Inf];
            app.ScanFromSpinner.ValueChangedFcn = createCallbackFcn(app, @ScanFromSpinnerValueChanged, true);
            app.ScanFromSpinner.Value = 1;
            app.toLabel = uilabel(app.Panel);
            app.toLabel.Text = 'to';
            app.ScanToEditField = uieditfield(app.Panel, 'numeric');
            app.ScanToEditField.UpperLimitInclusive = 'off';
            app.ScanToEditField.ValueChangedFcn = createCallbackFcn(app, @ScanToEditFieldValueChanged, true);
            app.ScanToEditField.Value = 1;
            app.NumberOfScansLabel = uilabel(app.Panel);
            app.NumberOfScansLabel.Text = '';
            app.CopyfigureButton = uibutton(app.DataTab, 'push');
            app.CopyfigureButton.ButtonPushedFcn = createCallbackFcn(app, @CopyfigureButtonPushed, true);
            app.CopyfigureButton.Text = 'Copy figure';
            app.RemoveScan = uibutton(app.DataTab, 'push');
            app.RemoveScan.ButtonPushedFcn = createCallbackFcn(app, @RemoveScanButtonPushed, true);
            app.RemoveScan.Enable = 'off';
            app.RemoveScan.Tooltip = {'If Scans mode is selected, click this to remove all displayed Scans from the current ScanSet - eg if there are individual temperature points with anomalous jumps in.'};
            app.RemoveScan.Text = 'Remove Scans';
            app.CopyCurvestoCustomPlotPanel = uipanel(app.DataTab);
            app.CopyCurvestoCustomPlotPanel.Title = 'Copy Curves to Custom Plot';
            app.BlueButton = uibutton(app.CopyCurvestoCustomPlotPanel, 'push');
            app.BlueButton.ButtonPushedFcn = createCallbackFcn(app, @BlueButtonPushed, true);
            app.BlueButton.Tooltip = {'Copy the currently displayed data onto the Custom Plots Tab - in blue. Probably best done with a single displayed plot, eg to compare a signal and background trace.'};
            app.BlueButton.Text = 'Blue';
            app.RedButton = uibutton(app.CopyCurvestoCustomPlotPanel, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Tooltip = {'Copy the currently displayed data onto the Custom Plots Tab - in red. Probably best done with a single displayed plot, eg to compare a signal and background trace.'};
            app.RedButton.Text = 'Red';
            app.GreenButton = uibutton(app.CopyCurvestoCustomPlotPanel, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Tooltip = {'Copy the currently displayed data onto the Custom Plots Tab - in green. Probably best done with a single displayed plot, eg to compare a signal and background trace.'};
            app.GreenButton.Text = 'Green';
            app.ResultsTab = uitab(app.TabGroup2);
            app.ResultsTab.Title = 'Results';
            app.ResultsAxes = uiaxes(app.ResultsTab);
            xlabel(app.ResultsAxes, 'x axis')
            ylabel(app.ResultsAxes, 'Moment (emu)')
            app.ResultsAxes.XTickLabelRotationMode = 'manual';
            app.ResultsAxes.YTickLabelRotationMode = 'manual';
            app.ResultsAxes.ZTickLabelRotationMode = 'manual';
            app.CopyfigureButton_2 = uibutton(app.ResultsTab, 'push');
            app.CopyfigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @CopyfigureButton_2Pushed, true);
            app.CopyfigureButton_2.Text = 'Copy figure';
            app.CustomPlotsTab = uitab(app.TabGroup2);
            app.CustomPlotsTab.Title = 'Custom Plots';
            app.CustomPlotAxes = uiaxes(app.CustomPlotsTab);
            xlabel(app.CustomPlotAxes, 'x axis')
            ylabel(app.CustomPlotAxes, 'y axis')
            app.CopyfigureButton_3 = uibutton(app.CustomPlotsTab, 'push');
            app.CopyfigureButton_3.ButtonPushedFcn = createCallbackFcn(app, @CopyfigureButton_3Pushed, true);
            app.CopyfigureButton_3.Text = 'Copy figure';
            app.ClearButton = uibutton(app.CustomPlotsTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.StatusPanel = uipanel(app.SquidLabUIFigure);
            app.Image = uiimage(app.SquidLabUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'SquidLabLogo.png');
            app.SquidLabv296Label = uilabel(app.SquidLabUIFigure);
            app.SquidLabv296Label.Text = 'SquidLab v2.9.6';
            app.MCoakDJarvisCLiu2019Label = uilabel(app.SquidLabUIFigure);
            app.MCoakDJarvisCLiu2019Label.Text = 'M.Coak, D. Jarvis, C. Liu, 2019';
            % Show the figure after all components are created
            app.SquidLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SquidLab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SquidLabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SquidLabUIFigure)
        end
    end
end